classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        shearforce          matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
        Bendingmom          matlab.ui.control.UIAxes
        UITable             matlab.ui.control.Table
        UITable2            matlab.ui.control.Table
        UITable3            matlab.ui.control.Table
        ReactionsLabel      matlab.ui.control.Label
        ForceExtremLabel    matlab.ui.control.Label
        SectionDataLabel    matlab.ui.control.Label
        AxialForce          matlab.ui.control.UIAxes
        BeamLabel           matlab.ui.control.Label
        ShearForceLabel     matlab.ui.control.Label
        BendingMomentLabel  matlab.ui.control.Label
        AxialForceLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
       
        supdata ;  % support's complete data 
        pfordata ;  % point's # # # # # # # 
        momentdata ;  % moment  # # # # # # # 
        DLdata ;  
        supcount = 0 ; % support number 
        momcount = 0 ; % moments # # # # # 
        lodcount = 0 ; % point loadd # # # # # #
        DLcount = 0 ; %distributed load's # # # # # #
        beamlength2 ; % last value imported as length of the beam 
        beamflag = 0 ; 
        pointflag = 0 ;
        supflag = 0 ;
        momflag = 0 ;
        DLflag = 0 ;
        maxpoin = 0 ; % maximum value  of point loads 
        maxdl = 0 ; % maximum value  of distributed loads 
        totdata ;
        reactdata % Description
        posofs;
        sizef;
        maxshear=0;
        minshear=0;
        maxbending=0;
        minbending=0;
        data;
        possec;
        ddata;
        maxax = 0;
        minax = 0;
        
    end
    
    methods (Access = public)
        function plot(app) % plot all 
            Lnw = app.beamlength2/ 10500 ; % linewidth of the axis 
            axis(app.UIAxes,'equal') ;
            if app.beamflag >= 1 % plot the beam 
                y = 0 ;
                x = 0 : 0.01*(app.beamlength2) : app.beamlength2 ;
                plot(app.UIAxes,x,y,'bs','MarkerfaceColor','b','linewidth',Lnw) ;
                hold(app.UIAxes,'on') ;
            end
            if app.supflag >= 1 
                
                    suptemp1 = app.supdata(:,1) ; % export the data of support ;
                    suptemp2 = app.supdata(:,2) ; 
                    msuptemp = cell2mat(suptemp1) ;
                    tsuptemp = string(suptemp2) ; 
                i = 1 ;
                % plot the supports of a beam : 
                    for i = 1 : app.supcount
                        LoadScale = 0-app.beamlength2 / 55 ;
                        if tsuptemp(i) == "Pin"
                        plot(app.UIAxes,msuptemp(i),LoadScale,'r^','MarkerfaceColor','r','MarkerSize',10)
                        hold(app.UIAxes,'on')
                        end
                     
                        if tsuptemp(i) == "Roller"
                            plot(app.UIAxes,msuptemp(i),LoadScale,'ro','MarkerfaceColor','r','MarkerSize',10)
                            hold(app.UIAxes,'on')
                        end
                 
                        if tsuptemp(i) == "Fixed"
                            plot(app.UIAxes,msuptemp(i),LoadScale,'rs','MarkerfaceColor','r','MarkerSize',10)
                            hold(app.UIAxes,'on')
                        end
                 
                    end   %for         
            end % if 
            if app.pointflag >= 1 
                        posload = app.pfordata(:,2) ;
                        anglload = app.pfordata(:,1) ;
                        magload = app.pfordata(:,3) ;
                        matposload = cell2mat(posload) ;
                        matanglload = cell2mat(anglload) ;
                        matmagload = cell2mat(magload) ;
                        
                        for i = 1 : app.lodcount 
                            if matmagload(i) > app.maxpoin 
                                app.maxpoin = matmagload(i) ;
                            end
                        end
                        for j = 1 : app.lodcount 
                    
                            Xi = cosd(matanglload(j,1)) ;
                            Yi = sind(matanglload(j,1)) ;
                            lnw = log((app.beamlength2+exp(1)) / exp(1))/2;                   
                            PionScale = log(((matmagload(j) / (app.maxpoin))+ exp(1))) * (app.beamlength2/5)
                            pionscale = (exp(matmagload(j) / (app.maxpoin)) + exp(1))/(2*exp(1)) ;
                            quiver(app.UIAxes,matposload(j,1),0,Xi,Yi,PionScale,'color','[1.00,0.86,0.16]','linewidth',lnw)                
                        end
            end %if 
            if app.momflag >= 1
                
                    dimom = app.momentdata(:,1) ;
                    posmom = app.momentdata(:,2) ;
                    magmom = app.momentdata(:,3) ;
                    dimomtemp = string(dimom) ;
                    posmomtemp = cell2mat(posmom) ;
                    magmomenttemp = cell2mat(magmom) ;
                        for g = 1 : app.momcount 
                            Lnw = .17 * app.beamlength2 ;
                            r = (1 / 20) * app.beamlength2 ;
                            if dimomtemp(g) == "Clockwise" 
                                X = posmomtemp(g) ;
                                tet = linspace(-.5 * pi,-2 * pi) ;
                                x = r * cos(tet) + X ;
                                y = r * sin(tet) + 0 ;
                                plot(app.UIAxes,x,y,'LineWidth',Lnw,'color','r') ;
                                hold(app.UIAxes,'on')
                                quiver(app.UIAxes,X + r,0,0,-1,1/10,'Maxheadsize',1,'LineWidth',Lnw,'color','r') ;
                            end
                            if dimomtemp(g) == "Counter Clockwise"
                                X = posmomtemp(g) ;
                                tet = linspace(-.5 * pi,1 * pi) ;
                                x = r * cos(tet) + X ;
                                y = r * sin(tet) + 0 ;
                                plot(app.UIAxes,x,y,'LineWidth',Lnw,'color','r') ;
                                hold(app.UIAxes,'on')
                                quiver(app.UIAxes,X - r,0,0,-1,1/10,'Maxheadsize',1,'LineWidth',Lnw,'color','r') ;
                            end
                        end % for
            end % if
            if app.DLflag >= 1
                    Lnw = .17 * app.beamlength2 ; 
                    mnumberdata = cell2mat(app.DLdata(:,[2,3])) ;
                    drdl = string(app.DLdata(:,1)) ;
                    Sf  = string(app.DLdata(:,4)) ;
                   
                    % plot :
                    h = 0 ;
                for h = 1 : app.DLcount
                    x = linspace(mnumberdata(h,1),mnumberdata(h,2)) ;
                    Y = inline(Sf(h)) ;
                    y = Y(x)
                    ay(h,:) = y; 
                     % app.maxdl = max(max(abs(y))) 
                end
                
                for i = 1:app.DLcount
                    
                    for j=1:100
                        yc = abs(ay(i,j));
                        
                        if yc>= app.maxdl 
                            app.maxdl = yc; 
                        end
                        
                    end
                end
                 for h = 1 : app.DLcount 
                 x = linspace(mnumberdata(h,1),mnumberdata(h,2)) ;
                 Y = inline(Sf(h)) ;
                 y = Y(x)
                 if app.maxdl < app.maxpoin
                         app.maxdl = app.maxpoin;
                 end
                 if max(abs(y))<app.maxdl 
                            y = y*1 / max(abs(y))*app.beamlength2 / 10  ;
                 else
                            y = y / app.maxdl * app.beamlength2 / 3  
                 end
                 
                if  drdl(h) == "Up"
                    plot(app.UIAxes,x,y + 0,'color','[.93,.70,.70]') ;
                end
                if drdl(h) == "Down"
                   plot(app.UIAxes,x,-y + 0,'color','[.93,.63,.63]') ;
                end
                j = 0;
                if (mnumberdata(h,2)-mnumberdata(h,1))/app.beamlength2 <0.5
                    q = 9;
                else
                    q = 1
                end
                for j = 1 : q : 100
                    
                          
                           if drdl(h) == "Up"
                               ys =  0 ;
                               scale = abs(y(j));
                                if y(j) < 0 
                                v = -1 ;
                           end
                           if y(j) >= 0 
                               v = 1 ;
                           end
                           end
                           if drdl(h) == "Down"
                               ys = 0 ;
                               scale = abs(y(j));
                                if y(j) < 0 
                               v = 1 ;
                           end
                           if y(j) >= 0 
                               v = -1 ;
                           end
                           end
                           if abs(y(j)) == 0 
                               continue
                           else 
                                quiver(app.UIAxes,x(j),ys,0,v,scale,'color','[.93,.63,.63]')                       
                           end            
                end
                 end    % 245 for h           
            end      % if app.dlflag>=1                   
             hold(app.UIAxes,'off')            
        end   % end of function                        
             
        
        function ssolve(app)
            % support :
            
            syms fsup [app.supcount 3]
            syms psup [app.supcount 1]
            suptemp1 = app.supdata(:,1) ; % export the data of support ;
            suptemp2 = app.supdata(:,2) ; 
            msuptemp = cell2mat(suptemp1) ;
            tsuptemp = string(suptemp2) ; 
            % make syms of supports :
            for i = 1 : app.supcount
                if tsuptemp(i) == "Roller"
                    subs(fsup(i,1),0) 
                   subs(fsup(i,3),0) 
                end
                if tsuptemp(i) == "Pin"
                    subs(fsup(i,3),0) 
                end
                psup(i) = msuptemp(i);
            end 
             sf = [0,0,0] ;
              for i = 1 : app.supcount
                sf = sf + [fsup(i,1),fsup(i,2),0];
              end
              sm = [0,0,0]; 
              for i = 1 : app.supcount
                sm = sm + cross([psup(i),0,0],[fsup(i,1),fsup(i,2),0]) +[0,0,fsup(i,3)] ;
                end
            %---------- loads----------
            if app.pointflag>=1
            % vector of loads 
            syms vload [app.lodcount 3] 
            % position of loads
            syms poload [app.lodcount 1]
            posload = app.pfordata(:,2) ;
            anglload = app.pfordata(:,1) ;
            magload = app.pfordata(:,3) ;
            matposload = cell2mat(posload) ;
            matanglload = cell2mat(anglload) ;
            matmagload = cell2mat(magload) ;
            for i = 1 : app.lodcount
                vload(i,:) = matmagload(i) * [cosd(matanglload(i)),sind(matanglload(i)),0] ;
                poload(i) = matposload(i) ;
            end
           
            for i = 1 : app.lodcount
                sf = sf + vload(i,:);
            end
           
            
            for i = 1 : app.lodcount
                sm = sm + cross([poload(i),0,0],vload(i,:)) ;
            end
           
            end
            % ---------- distributed load-----------
             if app.DLflag >= 1
            mnumberdata = cell2mat(app.DLdata(:,[2,3])) 
            drdl = string(app.DLdata(:,1)) ;
            Sf  = string(app.DLdata(:,4)) ;
            
             for i=1:app.DLcount
                  syms x 
                  Y = inline(Sf(i)) ;
                  y = Y(x)
                if drdl(i) == "Down"
                 sf = sf +[0,int(-y,x,mnumberdata(i,1),mnumberdata(i,2)),0]
                 sm = sm+[0,0,int(-x*y,x,mnumberdata(i,1),mnumberdata(i,2))]
                end
                if drdl(i) == "Up"
                   sf = sf + [0,int(y,x,mnumberdata(i,1),mnumberdata(i,2)),0]
                   sm = sm + [0,0,int(x*y,x,mnumberdata(i,1),mnumberdata(i,2))]
                end
             end
             end
             
            %---------moment---------
             if app.momflag >= 1
            dimom = app.momentdata(:,1) ;
            posmom = app.momentdata(:,2) ;
            magmom = app.momentdata(:,3) ;
            dimomtemp = string(dimom) ;
            posmomtemp = cell2mat(posmom) ;
            magmomenttemp = cell2mat(magmom) ;
            syms mom [app.momcount 3]
            for i=1 : app.momcount
                if dimomtemp(i) == "Clockwise"
                    mom(i,:) = [0,0,-1*magmomenttemp(i)];
                end
                if dimomtemp(i) == "Counter Clockwise"
                    mom(i,:) = [0,0,1*magmomenttemp(i)];
                end
            end
            for i = 1 :app.momcount
                sm = sm + mom(i,:);
            end
             end 
             %         solve
            eq = [sf==0,sm==0];
            [fsup] = solve (eq,fsup)
            fsup = struct2cell(fsup);
             fsup = cell2sym(fsup);
             fsup = double(fsup)
             
            for i = 1 : app.supcount
                app.UITable.Data = app.reactdata;
               nr={convertStringsToChars(tsuptemp(i)), fsup(i),fsup(i+1*app.supcount),fsup(i+2*app.supcount)}
               app.reactdata = [app.reactdata;nr];
            end
           
            app.UITable.Data = app.reactdata;
            if app.supcount==0
                suptemp1 = 0; 
            end
            if app.lodcount ==0 
                matposload=0; 
            end
            if app.momcount ==0 
                posmom= 0
            end 
            if app.DLcount==0
                mnumberdata(:,1) = 0 ;
                mnumberdata(:,2) = 0;
            end
            app.posofs = [matposload',suptemp1',posmom',mnumberdata(:,1)',mnumberdata(:,2)'];
            app.posofs = unique( sort(cell2mat(app.posofs)))
            app.sizef = size(app.posofs) 
            app.sizef = app.sizef(2) 
            reactbase = cell2mat(app.reactdata(:,[2,3,4]));


             avg1=0;
               syms X
               temp = 0 
           % ------------diagram --------------
             for i=1 :app.sizef
                 ssf = X*0 + 0 ;
                 sm = X*0 + 0 ;
                 sax = X*0 + 0 ;
                 temp = 0 ;
                 if i < app.sizef
                avg = (app.posofs(i)+app.posofs(i+1))/2; 
                 end
                 if i ==app.sizef
                     avg = app.beamlength2;
                 end
               for j=1:app.supcount
                   if msuptemp(j)<avg
                       ssf = ssf + reactbase(j,2);
                       sax = sax + reactbase(j,1);
                       sm = sm - reactbase(j,2)*msuptemp(j);
                   end
               end
             if app.lodcount>0
               for j=1:app.lodcount
                   if matposload(j)<avg
                       ssf= ssf + vload(j,2);
                       sax = sax + vload(j,1);
                      sm = sm - vload(j,2)*matposload(j);
                   end
               end
             end
           
               if app.DLcount>0
               for  j=1:app.DLcount
                   
                   if mnumberdata(j,1)<avg
                     syms x
                     Y = inline(Sf(j)) ;
                     y = Y(x)
                     if drdl(j) == "Up"
                         flag=1;
                     end
                     if drdl(j) == "Down"
                         flag=-1;
                     end

                       if mnumberdata(j,2)<avg
                           ssf = ssf + flag*int(y,x,mnumberdata(j,1),mnumberdata(j,2));
                           sm = sm - flag*int(x*y,x,mnumberdata(j,1),mnumberdata(j,2));
                       end
                       if mnumberdata(j,2)>avg
                           ssf = ssf + flag*int(y,x,mnumberdata(j,1),X);
                           sm = sm -flag*int(x*y,x,mnumberdata(j,1),X);
                       end
                   end
                   
               end
               end 
             
               if i<app.sizef 
                    p= linspace(app.posofs(i),app.posofs(i+1));                    
                    y = subs(ssf,X,p);   
                    H = subs(sax,X,p);
                    if max(abs(y))>app.maxshear
                        app.maxshear = max(abs(y));
                    end
                    if min(abs(y))<app.minshear
                        app.minshear = min(abs(y));
                    end
                    if max(abs(H))>app.maxax
                        app.maxax = max(abs(H));
                    end
                    if min(abs(H))<app.minax
                        app.minax = min(abs(H));
                    end
                       area(app.shearforce,p,y,'FaceColor','[0.3010 0.7450 0.9330]','EdgeColor','[0.3010 0.7450 0.9330]')
                       area(app.AxialForce,p,H,'FaceColor','[0.3010 0.7450 0.9330]','EdgeColor','[0.3010 0.7450 0.9330]')
                       hold(app.shearforce,'on')
                       hold(app.AxialForce,'on')
                       plot(app.shearforce,p,y,'Color','[0 0.4470 0.7410]')  
                       plot(app.AxialForce,p,H,'Color','[0 0.4470 0.7410]')                                           
               end
                   if i==app.sizef
                       p = linspace(app.posofs(i),app.beamlength2);
                       y = subs(ssf,X,p);
                       H = subs(sax,X,p);
                       if max(abs(y))>app.maxshear
                          app.maxshear = max(abs(y));
                       end
                       if min(abs(y))<app.minshear
                          app.minshear = min(abs(y));
                       end
                       if max(abs(H))>app.maxax
                          app.maxax = max(abs(H));
                       end
                       if min(abs(H))<app.minax
                          app.minax = min(abs(H));
                       end
                       area(app.shearforce,p,y,'FaceColor','[0.3010 0.7450 0.9330]','EdgeColor','[0.3010 0.7450 0.9330]')
                       area(app.AxialForce,p,H,'FaceColor','[0.3010 0.7450 0.9330]','EdgeColor','[0.3010 0.7450 0.9330]')
                       hold(app.shearforce,'on')
                       hold(app.AxialForce,'on')
                       plot(app.shearforce,p,y,'Color','[0 0.4470 0.7410]')  
                       plot(app.AxialForce,p,H,'Color','[0 0.4470 0.7410]')  
                       
                   end
                 sm = sm + ssf*X;
            if app.momcount>0
                   for j=1 : app.momcount
                     if posmomtemp(j)<avg
                       if dimomtemp(j) == "Clockwise"
                        flag =1;
                       end
                       if dimomtemp(j) == "Counter Clockwise"
                       flag=-1;
                       end
                      sm = sm+flag*(magmomenttemp(j));
                     end
                   end
           end
                 for j = 1 :app.supcount
                     if msuptemp(j)<avg
                         sm  = sm - reactbase(j,3);
                     end
                 end
                 
                    
                  y = subs (sm,X,p);
                  if max(abs(y))>app.maxbending
                      app.maxbending = max(abs(y));
                  end
                  if min(y)<app.minbending
                      app.minbending = min(abs(y));
                  end
                  area(app.Bendingmom,p,y,'FaceColor','[0.3010 0.7450 0.9330]','EdgeColor','[0.3010 0.7450 0.9330]')
                  hold(app.Bendingmom,'on')
                  plot(app.Bendingmom,p,y,'Color','[0 0.4470 0.7410]')
                  % ----- section ------
                  if i < app.sizef                                      
                    if app.possec >= app.posofs(i) && app.possec <= app.posofs(i+1)
                        shear = double(subs(ssf,X,app.possec));
                        bending = double(subs(sm,X,app.possec));
                        axial = double(subs(sax,X,app.possec))
                        nr = {shear,bending,axial}
                        app.ddata = [app.ddata;nr]
                        app.UITable3.Data = app.ddata;
                    end
                  end
                  if i == app.sizef                                      
                    if app.possec >= app.posofs(i) && app.possec <= app.beamlength2
                        shear = double(subs(ssf,X,app.possec));
                        bending = double(subs(sm,X,app.possec));
                        axial = double(subs(sax,X,app.possec))
                        nr = {shear,bending,axial}
                        app.ddata = [app.ddata;nr]
                        app.UITable3.Data = app.ddata;
                    end
                  end 
                              
             end
              maben = double(app.maxbending)
              miben =  double(app.minbending)
              mish = double(app.minshear)
              mash = double(app.maxshear)
              maax = double(app.maxax)
              miax = double(app.minax)
              type = 'ShearForce'
              nr = {type,mash,mish}
              app.data = [app.data;nr]
              type = 'BendingMoment'
              nr = {type,maben,miben}
              app.data = [app.data;nr]
              type = 'AxialForce'
              nr = {type,maax,miax}
              app.data = [app.data;nr]
              app.UITable2.Data = app.data;
        end                                                                                                     
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.totdata =readcell("totalsaver.xlsx")
            app.beamlength2 = cell2mat(  app.totdata(1,1))
            app.beamflag = cell2mat(app.totdata(1,2))
            app.supcount = cell2mat(app.totdata(2,1))
            app.supflag = cell2mat(app.totdata(2,2))
            app.momcount = cell2mat(app.totdata(3,1))
            app.momflag = cell2mat(app.totdata(3,2))
            app.lodcount = cell2mat(app.totdata(4,1))
            app.pointflag = cell2mat(app.totdata(4,2))
            app.DLcount = cell2mat(app.totdata(5,1))
            app.DLflag = cell2mat(app.totdata(5,2))
            app.maxdl = cell2mat(app.totdata(6,1))
            app.maxpoin = cell2mat(app.totdata(6,2))
            app.possec = cell2mat(app.totdata(7,1))
            if app.supflag~= 0
            app.supdata = readcell("supsaver.xlsx")
            end
            if app.pointflag~= 0
            app.pfordata = readcell("pointloadsaver.xlsx")
            end
            if app.momflag~= 0
            app.momentdata = readcell("momentsaver.xlsx")
            end
            if app.DLflag~= 0
            app.DLdata = readcell("Dlsaver.xlsx")
            end
           plot(app);
           ssolve(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.shearforce = uiaxes(app.UIFigure);
            title(app.shearforce, 'Shear Force')
            xlabel(app.shearforce, 'X (m)')
            ylabel(app.shearforce, 'V (N)')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Beam')
            xlabel(app.UIAxes, 'X(m)')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Bendingmom = uiaxes(app.UIFigure);
            title(app.Bendingmom, 'Bending Momment')
            xlabel(app.Bendingmom, 'X (m)')
            ylabel(app.Bendingmom, 'M (N.m)')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'support at '; 'x'; 'y'; 'Mx'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Results'; 'Max'; 'Min'};
            app.UITable2.RowName = {''};
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'Bending momment'; 'Shear force'; 'Axial force'};
            app.UITable3.RowName = {};
            app.ReactionsLabel = uilabel(app.UIFigure);
            app.ReactionsLabel.Text = 'Reactions ';
            app.ForceExtremLabel = uilabel(app.UIFigure);
            app.ForceExtremLabel.Text = 'Force Extrem ';
            app.SectionDataLabel = uilabel(app.UIFigure);
            app.SectionDataLabel.Text = 'Section Data ';
            app.AxialForce = uiaxes(app.UIFigure);
            title(app.AxialForce, 'Axial Force')
            xlabel(app.AxialForce, 'X (m)')
            ylabel(app.AxialForce, 'P (N)')
            app.BeamLabel = uilabel(app.UIFigure);
            app.BeamLabel.Text = 'Beam';
            app.ShearForceLabel = uilabel(app.UIFigure);
            app.ShearForceLabel.Text = 'Shear Force ';
            app.BendingMomentLabel = uilabel(app.UIFigure);
            app.BendingMomentLabel.Text = 'Bending Moment';
            app.AxialForceLabel = uilabel(app.UIFigure);
            app.AxialForceLabel.Text = 'Axial Force';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end