classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        modelandload            matlab.ui.container.TabGroup
        ModelTab                matlab.ui.container.Tab
        beamandsupport          matlab.ui.container.TabGroup
        BeamTab                 matlab.ui.container.Tab
        beamadd                 matlab.ui.control.Button
        BeamlengthLabel         matlab.ui.control.Label
        beamlength              matlab.ui.control.NumericEditField
        UnitLabel               matlab.ui.control.Label
        UnitBeam                matlab.ui.control.DropDown
        Image12                 matlab.ui.control.Image
        SupportsTab             matlab.ui.container.Tab
        SuppprtTypeButtonGroup  matlab.ui.container.ButtonGroup
        PinButton               matlab.ui.control.ToggleButton
        RollerButton            matlab.ui.control.ToggleButton
        FixedButton             matlab.ui.control.ToggleButton
        Image6                  matlab.ui.control.Image
        Image7                  matlab.ui.control.Image
        Image13                 matlab.ui.control.Image
        supportadd              matlab.ui.control.Button
        supportposition         matlab.ui.control.NumericEditField
        CounterSpinner_3Label   matlab.ui.control.Label
        supportspiner           matlab.ui.control.Spinner
        UITable                 matlab.ui.control.Table
        PositionLabel_3         matlab.ui.control.Label
        UnitLabel_2             matlab.ui.control.Label
        Unitsup                 matlab.ui.control.DropDown
        SectionTab              matlab.ui.container.Tab
        PositionLabel_4         matlab.ui.control.Label
        Possec                  matlab.ui.control.NumericEditField
        SECADD                  matlab.ui.control.Button
        UnitLabel_3             matlab.ui.control.Label
        Unitsection             matlab.ui.control.DropDown
        Image14                 matlab.ui.control.Image
        LoadsTab                matlab.ui.container.Tab
        TabGroup3               matlab.ui.container.TabGroup
        PointLoadTab            matlab.ui.container.Tab
        PositionLabel           matlab.ui.control.Label
        ponitloadposition       matlab.ui.control.NumericEditField
        pointloadadd            matlab.ui.control.Button
        pointloadmag            matlab.ui.control.NumericEditField
        AngleLabel              matlab.ui.control.Label
        CounterSpinnerLabel     matlab.ui.control.Label
        pointloadconuter        matlab.ui.control.Spinner
        degreeEditFieldLabel    matlab.ui.control.Label
        degreeEditField         matlab.ui.control.NumericEditField
        Image                   matlab.ui.control.Image
        MagnitudeLabel          matlab.ui.control.Label
        ploadtable              matlab.ui.control.Table
        UnitLabel_4             matlab.ui.control.Label
        UnitPL                  matlab.ui.control.DropDown
        UnitLabel_5             matlab.ui.control.Label
        UnitML                  matlab.ui.control.DropDown
        MomentsTab              matlab.ui.container.Tab
        PositionLabel_2         matlab.ui.control.Label
        AddButton               matlab.ui.control.Button
        MagnitudeLabel_2        matlab.ui.control.Label
        momentposition          matlab.ui.control.NumericEditField
        momentmag               matlab.ui.control.NumericEditField
        DirectionButtonGroup    matlab.ui.container.ButtonGroup
        cwButton                matlab.ui.control.ToggleButton
        ccwButton               matlab.ui.control.ToggleButton
        Image11                 matlab.ui.control.Image
        Image11_2               matlab.ui.control.Image
        momentable              matlab.ui.control.Table
        counterLabel            matlab.ui.control.Label
        Momentcoun              matlab.ui.control.Spinner
        UnitLabel_6             matlab.ui.control.Label
        UnitPM                  matlab.ui.control.DropDown
        UnitLabel_7             matlab.ui.control.Label
        UnitMM                  matlab.ui.control.DropDown
        DistributedLoadsTab     matlab.ui.container.Tab
        DLtype                  matlab.ui.container.ButtonGroup
        Up                      matlab.ui.control.ToggleButton
        Down                    matlab.ui.control.ToggleButton
        Image9                  matlab.ui.control.Image
        StartPosxLabel          matlab.ui.control.Label
        SPx                     matlab.ui.control.NumericEditField
        EndPosxLabel            matlab.ui.control.Label
        EPx                     matlab.ui.control.NumericEditField
        Functionx               matlab.ui.control.Label
        Fx                      matlab.ui.control.EditField
        DLadd                   matlab.ui.control.Button
        DLtable                 matlab.ui.control.Table
        CounterSpinnerLabel_2   matlab.ui.control.Label
        DLsppiner               matlab.ui.control.Spinner
        UnitLabel_8             matlab.ui.control.Label
        UnitSPD                 matlab.ui.control.DropDown
        UnitLabel_9             matlab.ui.control.Label
        UnitEPD                 matlab.ui.control.DropDown
        UnitLabel_10            matlab.ui.control.Label
        UnitFD                  matlab.ui.control.DropDown
        UIAxes                  matlab.ui.control.UIAxes
        Lamp                    matlab.ui.control.Lamp
        SolveButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
      supdata ;  % support's complete data 
      pfordata ;  % point's # # # # # # # 
      momentdata ;  % moment  # # # # # # # 
      DLdata ;  % distributed load's  # # # # # #
      supcount = 0 ; % support number 
      momcount = 0 ; % moments # # # # # 
      lodcount = 0 ; % point loadd # # # # # #
      DLcount = 0 ; %distributed load's # # # # # #
      beamlength2 ; % last value imported as length of the beam 
      beamflag = 0 ; 
      pointflag = 0 ;
      supflag = 0 ;
      momflag = 0 ;
      DLflag = 0 ;
      secflag=0;
      maxpoin = 0 ; % maximum value  of point loads 
      maxdl = 0 ; % maximum value  of distributed loads 
      totdata ;
      possec = 0 ;
     
      
    end
    
    methods (Access = public)
        function plot(app) % plot all 
        
            Lnw = app.beamlength2/ 10500 ; % scale linewidth of the axis 
            axis(app.UIAxes,'equal') ;
            if app.beamflag >= 1 % plot the beam 
                y = 0 ;
                x = 0 : 0.01*(app.beamlength2) : app.beamlength2 ; % scale for linspace
                plot(app.UIAxes,x,y,'bs','MarkerfaceColor','b','linewidth',Lnw) ; % blue square that has scale related to beam length
                hold(app.UIAxes,'on') ; % it is important not to lose our beam after adding data
            end
            if app.supflag >= 1 % we must be sure that user add data 
                
                    suptemp1 = app.supdata(:,1) ; % export the column of position of support  
                    suptemp2 = app.supdata(:,2) ; % export the column of type  of support  
                    msuptemp = cell2mat(suptemp1) ; % convert the cell array of support position to matrix
                    tsuptemp = string(suptemp2) ; % convert the cell array of support type to matrix
                i = 1 ;
                % plot the supports of a beam : 
                    for i = 1 : app.supcount
                        LoadScale = 0-app.beamlength2 / 55 ; % scale
                        if tsuptemp(i) == "Pin"
                        plot(app.UIAxes,msuptemp(i),LoadScale,'r^','MarkerfaceColor','r','MarkerSize',10) % for pin we used red triangle
                        hold(app.UIAxes,'on')
                        end
                     
                        if tsuptemp(i) == "Roller"
                            plot(app.UIAxes,msuptemp(i),LoadScale,'ro','MarkerfaceColor','r','MarkerSize',10) % for roller we used red circle
                            hold(app.UIAxes,'on')
                        end
                 
                        if tsuptemp(i) == "Fixed"
                            plot(app.UIAxes,msuptemp(i),LoadScale,'rs','MarkerfaceColor','r','MarkerSize',10) % for fixed we used red square
                            hold(app.UIAxes,'on')
                        end
                 
                    end   %for         
            end % if 
            if app.pointflag >= 1 % existent statement
                        posload = app.pfordata(:,2) ; % export the colunm of load's position
                        anglload = app.pfordata(:,1) ; % export the colunm of load's angle
                        magload = app.pfordata(:,3) ; % export the colunm of load's magnitude
                        matposload = cell2mat(posload) ; % convert the exported load'position column to matrix
                        matanglload = cell2mat(anglload) ; % convert the exported load'angle column to matrix
                        matmagload = cell2mat(magload) ; % convert the exported load'magnitude column to matrix
                        
                        for i = 1 : app.lodcount % find the maximum load magnitude 
                            if matmagload(i) > app.maxpoin 
                                app.maxpoin = matmagload(i) ;
                            end
                        end
                        for j = 1 : app.lodcount  % the load's plot loop
                    
                            Xi = cosd(matanglload(j,1)) ; % the horizental elemet of the load vector
                            Yi = sind(matanglload(j,1)) ; % the vertical elemet of the load vector
                            lnw = log((app.beamlength2+exp(1)) / exp(1))/2; % the linewidth scale  related to beam length             
                            PionScale = log(((matmagload(j) / (app.maxpoin))+ exp(1))) * (app.beamlength2/5); % the magnitude load scale formula related to beam length
                            quiver(app.UIAxes,matposload(j,1),0,Xi,Yi,PionScale,'color','[1.00,0.86,0.16]','linewidth',lnw)   % plot the vector of load            
                        end
            end %if 
            if app.momflag >= 1 % existent statement
                
                    dimom = app.momentdata(:,1) ; % export the column of type of moments
                    posmom = app.momentdata(:,2) ; % export the column of position of moments
                    magmom = app.momentdata(:,3) ; % export the column of magnitude of moments
                    dimomtemp = string(dimom) ; % convert the exported column from cell to string 
                    posmomtemp = cell2mat(posmom) ; % convert the exported column from cell to matris
                    magmomenttemp = cell2mat(magmom) ; % convert the exported column from cell to matris
                    
                        for g = 1 : app.momcount  % the plot loop
                            Lnw = .17 * app.beamlength2 ; % scale of quiver linewith
                            r = (1 / 20) * app.beamlength2 ; % scale of the radius of the moments circle 
                            if dimomtemp(g) == "Clockwise"  % type
                                X = posmomtemp(g) ; % position of moment
                                tet = linspace(-.5 * pi,-2 * pi) ; % the convertion from Cartesian  to Polar 
                                x = r * cos(tet) + X ; % cirle making 
                                y = r * sin(tet) + 0 ; % cirle making 
                                plot(app.UIAxes,x,y,'LineWidth',Lnw,'color','r') ; %plot the circle of moment 
                                hold(app.UIAxes,'on')
                                quiver(app.UIAxes,X + r,0,0,-1,1/10,'Maxheadsize',1,'LineWidth',Lnw,'color','r') ; % add a vector at the end of the circle for  dimention  
                            end
                            if dimomtemp(g) == "Counter Clockwise" % type
                                X = posmomtemp(g) ; % position of moment
                                tet = linspace(-.5 * pi,1 * pi) ; % the convertion from Cartesian  to Polar
                                x = r * cos(tet) + X ; % cirle making 
                                y = r * sin(tet) + 0 ; % cirle making 
                                plot(app.UIAxes,x,y,'LineWidth',Lnw,'color','r') ; %plot the circle of moment 
                                hold(app.UIAxes,'on')
                                quiver(app.UIAxes,X - r,0,0,-1,1/10,'Maxheadsize',1,'LineWidth',Lnw,'color','r') ; % add a vector at the end of the circle for  dimention
                            end
                        end % for
            end % if
            if app.secflag>=1 % existent statement 
                y = 0; % position of y 
                x = app.possec % position of x
               plot(app.UIAxes,x,y,'yx','MarkerfaceColor','y','MarkerSize',20) % plot a   cross sing as the position of the section user choosed
            end
            if app.DLflag >= 1 % existent statement
                    Lnw = .17 * app.beamlength2 ;  
                    mnumberdata = cell2mat(app.DLdata(:,[2,3])) ; % export the start and end position culomns of DL
                    drdl = string(app.DLdata(:,1)) ;  % export the type(up or down)  culomn of DL
                    Sf  = string(app.DLdata(:,4)) ; %  % export the function  culomn of DL
                   
                    h = 0 ;
                for h = 1 : app.DLcount % this loop put all functions data(linspace) in one matris so that we can find the max and min of all function for scale
                    x = linspace(mnumberdata(h,1),mnumberdata(h,2)) ;
                    Y = inline(Sf(h)) ; % convert string function to symbolic function
                    y = Y(x) % convert string function to symbolic function
                    ay(h,:) = y; % put all functions in ay matrix
                     % app.maxdl = max(max(abs(y))) 
                end
                
                for i = 1:app.DLcount % this loop find out what is the maximum of all DL so that we can have a good scale for it
                    
                    for j=1:100
                        yc = abs(ay(i,j)); % absuloute value is what we want perhaps a negetive value will be the max
                        
                        if yc>= app.maxdl 
                            app.maxdl = yc; 
                        end
                        
                    end
                end
                 for h = 1 : app.DLcount  % the plot of functions 
                 x = linspace(mnumberdata(h,1),mnumberdata(h,2)) ;
                 Y = inline(Sf(h)) ; % convert string function to symbolic function
                 y = Y(x); % convert string function to symbolic function
                 if app.maxdl < app.maxpoin % this loop find what fucntion is the one who has the max value 
                         app.maxdl = app.maxpoin; 
                 end
                 if max(abs(y))<app.maxdl % if the fuction was the first one higth of the max of it will be like this
                            y = y / max(abs(y))*app.beamlength2 / 10  ;
                 else
                            y = y / app.maxdl * app.beamlength2 / 3  ; % else it will be like this 
                 end
                 
                if  drdl(h) == "Up" % up or down is importent for the sing of function 
                    plot(app.UIAxes,x,y + 0,'color','[.93,.70,.70]') ; % y = y
                end
                if drdl(h) == "Down" % up or down is importent for the sing of function 
                   plot(app.UIAxes,x,-y + 0,'color','[.93,.63,.63]') ; % y = -y
                end
                
                % the quivers part :
                
                j = 0;
                if (mnumberdata(h,2)-mnumberdata(h,1))/app.beamlength2 <0.5 % some times when the period between star and end position quivers will ugly so we decrease their number
                    q = 9;
                else
                    q = 1 % but some times it is what we want and that would be perfect (like 10 m beam with 0 to 6.28 sin(x) :) )
                end
                
                % the quivers plot 
                
                for j = 1 : q : 100                                              
                           if drdl(h) == "Up"  % type 
                               ys =  0 ;
                               scale = abs(y(j)); % scale 
                                if y(j) < 0 
                                    v = -1 ; % the vertical quiver sing will be negative
                                end
                                if y(j) >= 0 
                                    v = 1 ; 
                                end
                           end
                           if drdl(h) == "Down"  % type
                               ys = 0 ;
                               scale = abs(y(j)); % scale
                                if y(j) < 0 
                                    v = 1 ;
                                end
                                if y(j) >= 0 
                                    v = -1 ; % the vertical quiver sing will be negative
                                end
                           end
                           if abs(y(j)) == 0  % when y(j) is equal to 0 we have to move on 
                               continue
                           else 
                                quiver(app.UIAxes,x(j),ys,0,v,scale,'color','[.93,.63,.63]')   % the quiver plot            
                           end            
                end
                 end    % 245 for h           
            end      % if app.dlflag>=1                   
             hold(app.UIAxes,'off')            
        end   % end of function                        
        function newnum = unitcon(app,num,type)
              if type == "ft" 
                newnum = 0.3048*num;
              end
              if type == "in" 
                newnum = 0.0254*num;
              end
              if type == "lbf" 
                newnum = 4.44822162*num;
              end
              if type == "lb.ft" 
                newnum = 1.36*num;
              end
              if type == "lb.in"
                newnum = 0.11298*num;
              end
              if type == "lb/ft" 
                newnum =14.5939029*num;
              end
              if type == "lb/in" 
                newnum = 175.126835*num;
              end
              if type =="m" || type =="N.m" || type == "N/m" || type == "N"
                    newnum = num;
              end
        end
     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: beamadd
        function beamaddButtonPushed(app, event)
           app.beamflag = app.beamflag + 1 ; % the flag that control user add a beam length
           type = app.UnitBeam.Value; % recieve the beam lentgh from the user
           
           app.beamlength2 = unitcon(app,app.beamlength.Value,type) ; % convertion of the beam lentgh unit
           
           plot(app)          % plot the beam   
        end

        % Button pushed function: supportadd
        function supportaddButtonPushed(app, event)
            app.supflag = app.supflag + 1 ; % flag to  know that user import data 
            % type of support 
            if app.PinButton.Value == true
                type = 'Pin' ;
            end
            
            if app.RollerButton.Value == true
                type = 'Roller' ;
            end
            
            if app.FixedButton.Value==true
                type = 'Fixed' ;
            end
            flag = 0 ;
            % this par is to know whether the user ad a new data or edit
            % the previous data
            if app.supportspiner.Value > app.supcount
                
                app.supcount = app.supportspiner.Value ; % user add new data 
                flag = 1 ;
                
            end
            
            app.UITable.Data = app.supdata ; % import supdata to table to show to user 
            typeu = app.Unitsup.Value;
            x = unitcon(app,app.supportposition.Value,typeu) ;             
            nr = {x type} ;
            if flag == 0
                app.supdata(app.supportspiner.Value,:) = nr ; % user edit data so the selected row will be changed
            end
            
            if flag == 1
                app.supdata = [app.supdata;nr] ; % user add data so that we add a row to subdata 
            end
            
            app.UITable.Data = app.supdata ;  % import subdata to table again!
         
            plot(app) ; % plot the supports on the axes
            
           
        end

        % Button pushed function: pointloadadd
        function pointloadaddButtonPushed(app, event)
            app.pointflag = app.pointflag + 1 ; % flag to  know that user import data 
            flag = 0 ;
            % this par is to know whether the user ad a new data or edit
            % the previous data
            if app.pointloadconuter.Value > app.lodcount
                app.lodcount = app.pointloadconuter.Value ;
                flag = 1 ;
            end       
            
            app.ploadtable.Data = app.pfordata ; % import point load data to pointload table 
            D = app.degreeEditField.Value ; % receive the angle of the load 
            typeu = app.UnitPL.Value;  %  receive the unit of the load's position   
            X = unitcon(app,app.ponitloadposition.Value,typeu) ; % convert the unit of the load's position  
            typeu = app.UnitML.Value;  %  receive the unit of the load's magnitude 
            M = unitcon(app,app.pointloadmag.Value,typeu) ; % convert the unit of the load's magnitude  
            nr = {D X M} ; % pfordatas load's row 
            
            if flag == 0
                app.pfordata(app.pointloadconuter.Value,:) = nr ; % user edit data like i explain in previous
            end
            
            if flag == 1
                app.pfordata = [app.pfordata;nr] ; %  user add data like i explain in previous
            end
            app.ploadtable.Data = app.pfordata ;  % import point load data to pointload table 
            plot(app) ; % plot the loads 
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.momflag = app.momflag + 1 ; % flag to  know that user import data 
            flag = 0 ;
            
            if app.Momentcoun.Value > app.momcount 
                app.momcount = app.Momentcoun.Value ; % user add data 
                flag = 1 ;
            end
            % recieve the type of moments
            if app.cwButton.Value == true
                direction = 'Clockwise' ;
            end
            
            if app.ccwButton.Value == true
                direction = 'Counter Clockwise' ;
            end
            
            app.momentdata = app.momentable.Data ; % import tabale data to suitable propertiy
            typeu = app.UnitPM.Value; % receive the units of the moment's position
            P = unitcon(app,app.momentposition.Value,typeu) ; % convert the units of the moment's position
            typeu = app.UnitMM.Value; % receive the units of the moment's magnitude
            M = unitcon(app,app.momentmag.Value,typeu) ; % convert the units of the moment's magnitude
            nr = {direction,P,M} ;
            if flag == 0
                app.momentdata(app.Momentcoun.Value,:) = nr ; % user edit data
            end
            
            if flag == 1
                app.momentdata = [app.momentdata;nr] ; % user add data 
            end
            
            app.momentable.Data = app.momentdata ; % import moments data 
            plot(app) ; % plot the moments
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices ;
            newData = event.NewData ;
            app.UITable.Data.support = categorical(app.UITable.Data.support,{'Pin','Roller','Fixed'}) ;
            
       
        end

        % Button pushed function: DLadd
        function DLaddPushed(app, event)
            app.DLflag = app.DLflag + 1 ; % flag to  know that user import data
            flag = 0 ;
            
            if app.DLsppiner.Value > app.DLcount
                app.DLcount = app.DLsppiner.Value ; % user add data
                flag = 1 ;
            end
            % type of distributed loads 
             if app.Up.Value== true
                direction = 'Up' ;
             end
             
            if app.Down.Value == true
                direction = 'Down' ;
            end
            
            typeu = app.UnitSPD.Value;  % receive the units of the star position of the Distributed load
            Sp = unitcon(app,app.SPx.Value,typeu) ;     % convert  the units of the star position of the Distributed load
            typeu = app.UnitEPD.Value;  % receive the units of the end position of the Distributed load
            Ep = unitcon(app,app.EPx.Value,typeu) ;     % convert  the units of the end position of the Distributed load
            F = app.Fx.Value ;   % receivefunction  Distributed load
            typeu = app.UnitFD.Value;   % recieve the units of the function of the Distributed load
            uni = unitcon(app,1,typeu) ;    % convert the units of the function of the Distributed load
            syms x
            Y = inline(F);   % convert the string function the user import to symbolic function
            y = Y(x)*uni;   % multiply th converted unit to the fuction so that there'll be no problem
            F = char(y);    % convert the symbolic function that we made to  char so that we can import it on the row
            nr = {direction,Sp,Ep,F} ;
            if flag == 0
                app.DLdata(app.DLcount,:) = nr ;    % user edit data
            end
            
            if flag == 1
                app.DLdata = [app.DLdata;nr] ;  % user add data 
            end
            
            app.DLtable.Data = app.DLdata ;     % import Dl data to table to show to user 
            plot(app) ;     % plot the distributec load
            
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
          fclose('all');        
        
          %  if statement is for if the user dont add the selected type of data there is no need to creat exel file 
          % else the code creat exel file import data to it and save it so
          % app 2 can read it and use 
          % it actually is a sharing file between project and app 2
          if app.supflag ~= 0 % existent statement
               data = app.supdata; % import data 
               filename = 'supsaver.xlsx'; % name of file 
               fopen(filename); % creat file 
               writecell(data,filename); % import data to file 
          end     
          
          if app.pointflag ~= 0  % existent statement
               data = app.pfordata; % import data
               filename = 'pointloadsaver.xlsx'; % name of file 
               fopen(filename); % creat file 
               writecell(data,filename);% import data to file
          end
          
          if app.momflag ~= 0  % existent statement
               data = app.momentdata; % import data
               filename = 'momentsaver.xlsx'; % name of file 
               fopen(filename);% creat file 
               writecell(data,filename);% import data to file
          end
          
          if app.DLflag ~= 0 % existent statement
              data = app.DLdata; % import data
              filename = 'Dlsaver.xlsx'; % name of file 
              fopen(filename); % creat file 
              writecell(data,filename);% import data to file
          end     
            % total data is flags,counters,max,min,positions of the program so it is really vital  
            app.totdata = [app.totdata ;{app.beamlength2,app.beamflag}];
            app.totdata = [app.totdata ;{app.supcount,app.supflag}];
            app.totdata = [app.totdata ;{app.momcount,app.momflag}];
            app.totdata = [app.totdata ;{app.lodcount,app.pointflag}];
            app.totdata = [app.totdata ;{ app.DLcount,app.DLflag}];
            app.totdata = [app.totdata ;{app.maxdl,app.maxpoin}];
            app.totdata = [app.totdata ;{app.possec,0}];            
            data = app.totdata; % import data 
            filename = 'totalsaver.xlsx'; % name of file 
            fopen(filename);% creat file 
            writecell(data,filename);% import data to file
            
            app2 ;     % open app2 
            
            fclose('all');       % close all file 
            % delete all files 
            delete("totalsaver.xlsx"); 
            delete("Dlsaver.xlsx");
            delete("momentsaver.xlsx");
            delete("pointloadsaver.xlsx");
            delete("supsaver.xlsx");


        end

        % Button pushed function: SECADD
        function SECADDPushed(app, event)
            app.possec = app.Possec.Value ;
            typeu = app.Unitsection.Value;
            app.possec = unitcon(app,app.possec,typeu);
            app.secflag = app.secflag+1;
            plot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.modelandload = uitabgroup(app.UIFigure);
            app.ModelTab = uitab(app.modelandload);
            app.ModelTab.Title = 'Model';
            app.beamandsupport = uitabgroup(app.ModelTab);
            app.BeamTab = uitab(app.beamandsupport);
            app.BeamTab.Title = 'Beam';
            app.beamadd = uibutton(app.BeamTab, 'push');
            app.beamadd.ButtonPushedFcn = createCallbackFcn(app, @beamaddButtonPushed, true);
            app.beamadd.Text = 'Add';
            app.BeamlengthLabel = uilabel(app.BeamTab);
            app.BeamlengthLabel.Text = 'Beam length:';
            app.beamlength = uieditfield(app.BeamTab, 'numeric');
            app.beamlength.Limits = [0 Inf];
            app.UnitLabel = uilabel(app.BeamTab);
            app.UnitLabel.Text = 'Unit :';
            app.UnitBeam = uidropdown(app.BeamTab);
            app.UnitBeam.Items = {'m', 'ft', 'in', ''};
            app.UnitBeam.Value = 'm';
            app.Image12 = uiimage(app.BeamTab);
            app.Image12.ImageSource = '1.jpg';
            app.SupportsTab = uitab(app.beamandsupport);
            app.SupportsTab.Title = 'Supports';
            app.SuppprtTypeButtonGroup = uibuttongroup(app.SupportsTab);
            app.SuppprtTypeButtonGroup.Title = 'Suppprt Type:';
            app.PinButton = uitogglebutton(app.SuppprtTypeButtonGroup);
            app.PinButton.Text = 'Pin';
            app.RollerButton = uitogglebutton(app.SuppprtTypeButtonGroup);
            app.RollerButton.Text = 'Roller';
            app.FixedButton = uitogglebutton(app.SuppprtTypeButtonGroup);
            app.FixedButton.Text = 'Fixed';
            app.FixedButton.Value = true;
            app.Image6 = uiimage(app.SuppprtTypeButtonGroup);
            app.Image6.ImageSource = 'Picture1.jpg.png';
            app.Image7 = uiimage(app.SuppprtTypeButtonGroup);
            app.Image7.ImageSource = 'roller.png';
            app.Image13 = uiimage(app.SuppprtTypeButtonGroup);
            app.Image13.ImageSource = 'fixed2.png';
            app.supportadd = uibutton(app.SupportsTab, 'push');
            app.supportadd.ButtonPushedFcn = createCallbackFcn(app, @supportaddButtonPushed, true);
            app.supportadd.Text = 'Add';
            app.supportposition = uieditfield(app.SupportsTab, 'numeric');
            app.supportposition.Limits = [0 Inf];
            app.CounterSpinner_3Label = uilabel(app.SupportsTab);
            app.CounterSpinner_3Label.Text = 'Counter';
            app.supportspiner = uispinner(app.SupportsTab);
            app.supportspiner.Limits = [1 Inf];
            app.supportspiner.Value = 1;
            app.UITable = uitable(app.SupportsTab);
            app.UITable.ColumnName = {'position'; 'support'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true];
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.PositionLabel_3 = uilabel(app.SupportsTab);
            app.PositionLabel_3.Text = 'Position:';
            app.UnitLabel_2 = uilabel(app.SupportsTab);
            app.UnitLabel_2.Text = 'Unit :';
            app.Unitsup = uidropdown(app.SupportsTab);
            app.Unitsup.Items = {'m', 'ft', 'in', ''};
            app.Unitsup.Value = 'm';
            app.SectionTab = uitab(app.beamandsupport);
            app.SectionTab.Title = 'Section ';
            app.PositionLabel_4 = uilabel(app.SectionTab);
            app.PositionLabel_4.Text = 'Position :';
            app.Possec = uieditfield(app.SectionTab, 'numeric');
            app.Possec.Limits = [0 Inf];
            app.SECADD = uibutton(app.SectionTab, 'push');
            app.SECADD.ButtonPushedFcn = createCallbackFcn(app, @SECADDPushed, true);
            app.SECADD.Text = 'Add';
            app.UnitLabel_3 = uilabel(app.SectionTab);
            app.UnitLabel_3.Text = 'Unit :';
            app.Unitsection = uidropdown(app.SectionTab);
            app.Unitsection.Items = {'m', 'ft', 'in', ''};
            app.Unitsection.Value = 'm';
            app.Image14 = uiimage(app.SectionTab);
            app.Image14.ImageSource = 'section (1).png';
            app.LoadsTab = uitab(app.modelandload);
            app.LoadsTab.Title = 'Loads';
            app.TabGroup3 = uitabgroup(app.LoadsTab);
            app.PointLoadTab = uitab(app.TabGroup3);
            app.PointLoadTab.Title = 'Point Load';
            app.PositionLabel = uilabel(app.PointLoadTab);
            app.PositionLabel.Text = 'Position:';
            app.ponitloadposition = uieditfield(app.PointLoadTab, 'numeric');
            app.ponitloadposition.Limits = [0 Inf];
            app.pointloadadd = uibutton(app.PointLoadTab, 'push');
            app.pointloadadd.ButtonPushedFcn = createCallbackFcn(app, @pointloadaddButtonPushed, true);
            app.pointloadadd.Text = 'Add';
            app.pointloadmag = uieditfield(app.PointLoadTab, 'numeric');
            app.pointloadmag.Limits = [0 Inf];
            app.AngleLabel = uilabel(app.PointLoadTab);
            app.AngleLabel.Text = 'Angle:';
            app.CounterSpinnerLabel = uilabel(app.PointLoadTab);
            app.CounterSpinnerLabel.Text = 'Counter';
            app.pointloadconuter = uispinner(app.PointLoadTab);
            app.pointloadconuter.Limits = [1 Inf];
            app.pointloadconuter.Value = 1;
            app.degreeEditFieldLabel = uilabel(app.PointLoadTab);
            app.degreeEditFieldLabel.Text = 'degree';
            app.degreeEditField = uieditfield(app.PointLoadTab, 'numeric');
            app.Image = uiimage(app.PointLoadTab);
            app.Image.ImageSource = 'force.png';
            app.MagnitudeLabel = uilabel(app.PointLoadTab);
            app.MagnitudeLabel.Text = 'Magnitude:';
            app.ploadtable = uitable(app.PointLoadTab);
            app.ploadtable.ColumnName = {'Angle'; 'Position'; 'Magnitude'};
            app.ploadtable.RowName = {};
            app.ploadtable.ColumnSortable = [true true true];
            app.ploadtable.ColumnEditable = [true true true];
            app.UnitLabel_4 = uilabel(app.PointLoadTab);
            app.UnitLabel_4.Text = 'Unit :';
            app.UnitPL = uidropdown(app.PointLoadTab);
            app.UnitPL.Items = {'m', 'ft', 'in', ''};
            app.UnitPL.Value = 'm';
            app.UnitLabel_5 = uilabel(app.PointLoadTab);
            app.UnitLabel_5.Text = 'Unit :';
            app.UnitML = uidropdown(app.PointLoadTab);
            app.UnitML.Items = {'N', 'lbf', ''};
            app.UnitML.Value = 'N';
            app.MomentsTab = uitab(app.TabGroup3);
            app.MomentsTab.Title = 'Moments';
            app.PositionLabel_2 = uilabel(app.MomentsTab);
            app.PositionLabel_2.Text = 'Position:';
            app.AddButton = uibutton(app.MomentsTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.MagnitudeLabel_2 = uilabel(app.MomentsTab);
            app.MagnitudeLabel_2.Text = 'Magnitude:';
            app.momentposition = uieditfield(app.MomentsTab, 'numeric');
            app.momentposition.Limits = [0 Inf];
            app.momentmag = uieditfield(app.MomentsTab, 'numeric');
            app.momentmag.Limits = [0 Inf];
            app.DirectionButtonGroup = uibuttongroup(app.MomentsTab);
            app.DirectionButtonGroup.Title = 'Direction:';
            app.cwButton = uitogglebutton(app.DirectionButtonGroup);
            app.cwButton.Tooltip = {'clockwise'};
            app.cwButton.Text = 'cw';
            app.cwButton.Value = true;
            app.ccwButton = uitogglebutton(app.DirectionButtonGroup);
            app.ccwButton.Tooltip = {'counter clockwise'};
            app.ccwButton.Text = 'ccw';
            app.Image11 = uiimage(app.DirectionButtonGroup);
            app.Image11.ImageSource = 'MOM CW.jpg.png';
            app.Image11_2 = uiimage(app.DirectionButtonGroup);
            app.Image11_2.ImageSource = 'MOM CCW.png';
            app.momentable = uitable(app.MomentsTab);
            app.momentable.ColumnName = {'Direction'; 'Position'; 'Magnitude'};
            app.momentable.RowName = {};
            app.momentable.ColumnSortable = [true true true];
            app.momentable.ColumnEditable = [true true true];
            app.counterLabel = uilabel(app.MomentsTab);
            app.counterLabel.Text = 'counter :';
            app.Momentcoun = uispinner(app.MomentsTab);
            app.Momentcoun.Limits = [1 Inf];
            app.Momentcoun.Value = 1;
            app.UnitLabel_6 = uilabel(app.MomentsTab);
            app.UnitLabel_6.Text = 'Unit :';
            app.UnitPM = uidropdown(app.MomentsTab);
            app.UnitPM.Items = {'m', 'ft', 'in', ''};
            app.UnitPM.Value = 'm';
            app.UnitLabel_7 = uilabel(app.MomentsTab);
            app.UnitLabel_7.Text = 'Unit :';
            app.UnitMM = uidropdown(app.MomentsTab);
            app.UnitMM.Items = {'N.m', 'lb.ft', 'lb.in'};
            app.UnitMM.Value = 'N.m';
            app.DistributedLoadsTab = uitab(app.TabGroup3);
            app.DistributedLoadsTab.Title = 'Distributed Loads';
            app.DLtype = uibuttongroup(app.DistributedLoadsTab);
            app.DLtype.Title = 'Direction:';
            app.Up = uitogglebutton(app.DLtype);
            app.Up.Text = 'Up';
            app.Up.Value = true;
            app.Down = uitogglebutton(app.DLtype);
            app.Down.Text = 'Down';
            app.Image9 = uiimage(app.DLtype);
            app.Image9.ImageSource = 'DLF.png';
            app.StartPosxLabel = uilabel(app.DistributedLoadsTab);
            app.StartPosxLabel.Text = 'Start Pos x:';
            app.SPx = uieditfield(app.DistributedLoadsTab, 'numeric');
            app.SPx.Limits = [0 Inf];
            app.EndPosxLabel = uilabel(app.DistributedLoadsTab);
            app.EndPosxLabel.Text = 'End Pos x:';
            app.EPx = uieditfield(app.DistributedLoadsTab, 'numeric');
            app.EPx.Limits = [0 Inf];
            app.Functionx = uilabel(app.DistributedLoadsTab);
            app.Functionx.Text = 'Function(x):';
            app.Fx = uieditfield(app.DistributedLoadsTab, 'text');
            app.DLadd = uibutton(app.DistributedLoadsTab, 'push');
            app.DLadd.ButtonPushedFcn = createCallbackFcn(app, @DLaddPushed, true);
            app.DLadd.Text = 'Add';
            app.DLtable = uitable(app.DistributedLoadsTab);
            app.DLtable.ColumnName = {'Direction'; 'Start Pos X'; 'End Pos X'; 'Function'};
            app.DLtable.RowName = {};
            app.DLtable.ColumnSortable = [true true true true];
            app.DLtable.ColumnEditable = [true true true true];
            app.CounterSpinnerLabel_2 = uilabel(app.DistributedLoadsTab);
            app.CounterSpinnerLabel_2.Text = 'Counter :';
            app.DLsppiner = uispinner(app.DistributedLoadsTab);
            app.DLsppiner.Limits = [1 Inf];
            app.DLsppiner.Value = 1;
            app.UnitLabel_8 = uilabel(app.DistributedLoadsTab);
            app.UnitLabel_8.Text = 'Unit :';
            app.UnitSPD = uidropdown(app.DistributedLoadsTab);
            app.UnitSPD.Items = {'m', 'ft', 'in', ''};
            app.UnitSPD.Value = 'm';
            app.UnitLabel_9 = uilabel(app.DistributedLoadsTab);
            app.UnitLabel_9.Text = 'Unit :';
            app.UnitEPD = uidropdown(app.DistributedLoadsTab);
            app.UnitEPD.Items = {'m', 'ft', 'in', ''};
            app.UnitEPD.Value = 'm';
            app.UnitLabel_10 = uilabel(app.DistributedLoadsTab);
            app.UnitLabel_10.Text = 'Unit :';
            app.UnitFD = uidropdown(app.DistributedLoadsTab);
            app.UnitFD.Items = {'N/m', 'lb/ft', 'lb/in'};
            app.UnitFD.Value = 'N/m';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Model')
            xlabel(app.UIAxes, 'X(m)')
            ylabel(app.UIAxes, '')
            app.Lamp = uilamp(app.UIFigure);
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end